classdef PbD < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PbDMadgwickAlgorithmSimulationUIFigure  matlab.ui.Figure
        GridLayout                matlab.ui.container.GridLayout
        UIAxes                    matlab.ui.control.UIAxes
        UIAxes_2                  matlab.ui.control.UIAxes
        UIAxes_3                  matlab.ui.control.UIAxes
        UIAxes_4                  matlab.ui.control.UIAxes
        RunProgramButton          matlab.ui.control.Button
        PbDDataButton             matlab.ui.control.Button
        RobotArmSimulationButton  matlab.ui.control.Button
        UpdateWaypointsButton     matlab.ui.control.Button
        StopButton                matlab.ui.control.Button
    end

    
    properties (Access = private)
        selectedWaypoint = 1;   % Currently selected waypoint on table
        
        robot;                  % Rigid Body Tree
        robotConfig;            % Rigid Body Tree joint configuration
        robotIK;                % Rigid Body Tree Inverse Kinematics solver
        robotIKWeights;         % Rigid Body Tree Inverse Kinematics weights
        robotIKInitGuess;       % Rigid Body Tree Inverse Kinematics initial guess
        robotEE;                % Rigid Body Tree End Effector frame

    end
    
    methods (Access = private)
        
        function madgwick(app)
            
            app.UIAxes.cla;
            app.UIAxes_2.cla;
            app.UIAxes_3.cla;
            app.UIAxes_4.cla;
            
            [pathname,dirname] = uigetfile('*.txt;');
            fullpath = fullfile(dirname,pathname);
            fid = fopen(fullpath,'rt');
            cac = textscan(fid, '%d%c%d %{HH:mm:ss.SSS}D %f%f%f %f%f%f %f%f%f %f%f%f%f', "Headerlines",2);
            fclose(fid);
            
            samplePeriod = 1/200; %data rate per minute (huge impact on integration process) 200
            
            %COMMENT
            g = 9.801; %gravity /1
            
            % Convert acceleration units to m/s^2
            accX = cac{5}*g; %all axis are affected by gravity
            accY = cac{6}*g;
            accZ = cac{7}*g;
            
            %gyro opt
            gyrX = cac{8};
            gyrY = cac{9};
            gyrZ = cac{10};
            
            time = cac{4};
            time = second(time);
            
            % -------------------------------------------------------------------------
            % Detect stationary periods
            
            % Compute accelerometer magnitude
            acc_mag = sqrt(accX.*accX + accY.*accY + accZ.*accZ);
            
            % % HighPass filter accelerometer data
            filtCutOff = 0.001;
            [b, a] = butter(1, (2*filtCutOff)/(1/samplePeriod), 'high');
            acc_magFilt = filtfilt(b, a, acc_mag);
            
            % Compute absolute value
            acc_magFilt = abs(acc_magFilt);
            
            % % LowPass filter accelerometer data
            filtCutOff = 5;
            [b, a] = butter(1, (2*filtCutOff)/(1/samplePeriod), 'low');
            acc_magFilt = filtfilt(b, a, acc_magFilt);
            
            % Threshold detection | IMPORTANT
            stationary = acc_magFilt < 0.045; %0.06 %0.045 cobakotak %0.05 cobalinear30
            
            % -------------------------------------------------------------------------
            % Plot data raw sensor data and stationary periods
            
%             ax(1) = subplot(2,1,2);
                hold (app.UIAxes_2,'on');
                plot(app.UIAxes_2,time, gyrX, 'r');
                plot(app.UIAxes_2,time, gyrY, 'g');
                plot(app.UIAxes_2,time, gyrZ, 'b');
                title(app.UIAxes_2,'Gyroscope');
                xlabel(app.UIAxes_2,'Time (s)');
                ylabel(app.UIAxes_2,'Angular velocity (deg/s)');
                legend(app.UIAxes_2,'X', 'Y', 'Z');
                hold (app.UIAxes_2,'off');
%             ax(2) = subplot(2,1,1);
                hold (app.UIAxes,'on');
                plot(app.UIAxes,time, accX, 'r');
                plot(app.UIAxes,time, accY, 'g');
                plot(app.UIAxes,time, accZ, 'b');
                plot(app.UIAxes,time, acc_magFilt, ':k');
                title(app.UIAxes,'Accelerometer');
                xlabel(app.UIAxes,'Time (s)');
                ylabel(app.UIAxes,'Acceleration (m/s2)');
                legend(app.UIAxes,'X', 'Y', 'Z', 'Filtered', 'Stationary');
                legend(app.UIAxes,'X', 'Y', 'Z', 'Filtered');
                hold (app.UIAxes,'off');
%             linkaxes(ax,'x');
            
            % -------------------------------------------------------------------------
            % Compute orientation
            
            quat = zeros(length(time), 4);
            AHRSalgorithm = AHRS('SamplePeriod', samplePeriod, 'Kp', 1, 'KpInit', 1);
            
            % For all data
            for t = 1:length(time)
                if(stationary(t))
                    AHRSalgorithm.Kp = 1;
                else
                    AHRSalgorithm.Kp = 0;
                end
                AHRSalgorithm.UpdateIMU(deg2rad([gyrX(t) gyrY(t) gyrZ(t)]), [accX(t) accY(t) accZ(t)]);
                quat(t,:) = AHRSalgorithm.Quaternion;
            end
            
            % -------------------------------------------------------------------------
            % Compute translational accelerations
            
            % Rotate body accelerations to Earth frame
            acc = quaternRotate([accX accY accZ], quaternConj(quat));
            
            % -------------------------------------------------------------------------
            % Compute translational velocities
            
            % Integrate acceleration to yield velocity
            vel = zeros(size(acc));
            
            for t = 2:length(vel) %vel/vel1
                vel(t,:) = vel(t-1,:) + acc(t,:) * samplePeriod; %delta time
                if(stationary(t) == 1)
                    vel(t,:) = [0 0 0]; % force zero velocity when stationary
                end
            end
            
            %COMMENT
            % % Compute integral drift during non-stationary periods
            velDrift = zeros(size(vel));
            stationaryStart = find([0; diff(stationary)] == -1);
            stationaryEnd = find([0; diff(stationary)] == 1);
            for i = 1:numel(stationaryEnd)
                driftRate = vel(stationaryEnd(i)-1, :) / (stationaryEnd(i) - stationaryStart(i));
                enum = 1:(stationaryEnd(i) - stationaryStart(i));
                drift = [enum'*driftRate(1) enum'*driftRate(2) enum'*driftRate(3)];
                velDrift(stationaryStart(i):stationaryEnd(i)-1, :) = drift;
            end
           
            % Remove integral drift
            vel = vel - velDrift;
            vel2 = (vel/2)*10000;
            dlmwrite('.\runProgramResult.txt', [vel2 gyrX gyrY gyrZ], 'delimiter', '\t', 'precision','%.4f');
            
            % % Plot translational velocity
%             figure('Position', [9 39 900 300], 'NumberTitle', 'off', 'Name', 'Velocity');
            hold (app.UIAxes_3, 'on');
            plot(app.UIAxes_3,time, vel(:,1), 'r');
            plot(app.UIAxes_3,time, vel(:,2), 'g');
            plot(app.UIAxes_3,time, vel(:,3), 'b');
            title(app.UIAxes_3,'Velocity');
            xlabel(app.UIAxes_3,'Time (s)');
            ylabel(app.UIAxes_3,'Velocity (m/s)');
            legend(app.UIAxes_3,'X', 'Y', 'Z');
            hold (app.UIAxes_3,'off');
            
            % -------------------------------------------------------------------------
            % Compute translational position
            
            % Integrate velocity to yield position
            pos = zeros(size(vel));
            
            for t = 2:length(pos)
            %     pos = cumtrapz(1/200, vel);
                pos(t,:) = pos(t-1,:) + vel(t,:) * samplePeriod;    % integrate velocity to gain position
            end
            
            % % Plot translational position
%             figure('Position', [9 39 900 600], 'NumberTitle', 'off', 'Name', 'Position');
            hold (app.UIAxes_4, 'on')
            plot(app.UIAxes_4,time, pos(:,1), 'r');
            plot(app.UIAxes_4,time, pos(:,2), 'g');
            plot(app.UIAxes_4,time, pos(:,3), 'b');
            title(app.UIAxes_4,'Position');
            xlabel(app.UIAxes_4,'Time (s)');
            ylabel(app.UIAxes_4,'Position (m)');
            legend(app.UIAxes_4,'X', 'Y', 'Z');
            hold (app.UIAxes_4, 'off') 
            
            answer = questdlg("Would you run the Simulation?", ...
                'Simulation', ...
                'Yes', 'No', 'No');
            
            switch answer
                case 'Yes'
                % -------------------------------------------------------------------------
                % 3D Plot
                posPlot = pos;
                quatPlot = quat;
                
                % Create 6 DOF animation create AVI = False
                SamplePlotFreq = 5;
                Spin = 100;
                PbDAnimation(posPlot, quatern2rotMat(quatPlot), ...
                                'SamplePlotFreq', SamplePlotFreq, 'Trail', 'All', ...
                                'Position', [250 35 860 640], 'View', [(20:(Spin/(length(posPlot)-1)):(20+Spin))', 20*ones(length(posPlot), 1)], ...
                                'AxisLength', 0.1, 'ShowArrowHead', false, ...
                                'Xlabel', 'X (m)', 'Ylabel', 'Y (m)', 'Zlabel', 'Z (m)', 'ShowLegend', false, ...
                                'CreateAVI', false, 'AVIfileNameEnum', false, 'AVIfps', ((1/samplePeriod) / SamplePlotFreq)); %mean deltaTime       
                case 'No'
                return
            end

            end
        
        function createRigidBodyTree(app)
            addpath('utilities');
            % Creates a rigid body tree and IK solver from the robot and end effector name fields
            evalin('base',['load ' 'gen3']);
            app.robot = evalin('base', 'gen3');
            app.robot.DataFormat = 'row';
            app.robotIK = inverseKinematics('RigidBodyTree',app.robot);
            app.robotIK.SolverParameters.MaxIterations = 10000;    % Limit the max iterations
            app.robotIKWeights = [1, 1, 1, 1, 1, 1];
            app.robotConfig = app.robot.homeConfiguration;
            app.robotIKInitGuess = app.robotConfig;
            app.robotEE = 'Gripper'; 
        end
        
        function showRobot(app)
            % Displays the waypoints, and the rigid body tree IK solution a provided waypoint index "idx"
            
            % Use the home configuration if the point is invalid
            app.robotConfig = app.robot.homeConfiguration;

            % Display the rigid body tree
            show(app.robot,app.robotConfig,'Frames','off');
        end
        
        function exportVariablesToWorkspace(~)
            % Exports the waypoint and time data to the MATLAB base workspace.
            % Used when clicking the "To Workspace" and "Update Diagram" buttons
            
            [pathname,dirname] = uigetfile('*.txt;');
            fullpath = fullfile(dirname,pathname);
            fid = fopen(fullpath,'rt');
            cac = textscan(fid, '%f%f%f%f%f%f%f');
            fclose(fid);
            time = cac{1};  
            posx = cac{2};
            posy = cac{3};
            posz = cac{4};
            gyrx = cac{5};
            gyry = cac{6};
            gyrz = cac{7};
            Home = [0.5 0.0015 0.4];
            HomeG = [1 1.5 1];
              
            time = rot90(time); % or use '
            pos = Home' + (rot90([posz posy posx]));
            gyr = HomeG' + (rot90([gyrz gyry gyrx]));
            
            assignin('base','waypointTimes',time);
            assignin('base','waypoints',pos);
            assignin('base','orientations',gyr);
        end
            end
                

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Create Rigid Body Tree
            createRigidBodyTree(app);
            run startupExample.m
            addpath('Quaternions Calculation');
            addpath('utilities');
        end

        % Button pushed function: RunProgramButton
        function RunProgramButtonPushed(app, event)
            madgwick(app)
            msgbox({'Operation Completed';'The Velocity Data Successfully Stored in Directory'}, 'PbD', 'modal')
        end

        % Button pushed function: PbDDataButton
        function PbDDataButtonPushed(app, event)
            system('notepad .\runProgramResult.txt');
        end

        % Button pushed function: RobotArmSimulationButton
        function RobotArmSimulationButtonPushed(app, event)
            mdlName = 'manipTransformTrajectory';
            if ~bdIsLoaded(mdlName)
                open_system(mdlName)
            end
%             set_param(mdlName,'maxWaypoints', '99999')
%             set_param(mdlName,'ts', '100')
            set_param(mdlName,'SimulationCommand','start');
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            mdlName = 'manipTransformTrajectory';
            set_param(mdlName,'SimulationCommand','stop');
        end

        % Button pushed function: UpdateWaypointsButton
        function UpdateWaypointsButtonPushed(app, event)
            exportVariablesToWorkspace(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.PbDMadgwickAlgorithmSimulationUIFigure = uifigure('Visible', 'off');
            app.PbDMadgwickAlgorithmSimulationUIFigure.Name = 'PbD | Madgwick Algorithm Simulation';
            app.GridLayout = uigridlayout(app.PbDMadgwickAlgorithmSimulationUIFigure);
            app.UIAxes = uiaxes(app.GridLayout);
            title(app.UIAxes, 'Accelerometer')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes_2 = uiaxes(app.GridLayout);
            title(app.UIAxes_2, 'Angular Velocity')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            app.UIAxes_3 = uiaxes(app.GridLayout);
            title(app.UIAxes_3, 'Velocity')
            xlabel(app.UIAxes_3, 'X')
            ylabel(app.UIAxes_3, 'Y')
            app.UIAxes_4 = uiaxes(app.GridLayout);
            title(app.UIAxes_4, 'Position')
            xlabel(app.UIAxes_4, 'X')
            ylabel(app.UIAxes_4, 'Y')
            app.RunProgramButton = uibutton(app.GridLayout, 'push');
            app.RunProgramButton.ButtonPushedFcn = createCallbackFcn(app, @RunProgramButtonPushed, true);
            app.RunProgramButton.Tooltip = {'Showing up an open dialog box to choose and run the file.'};
            app.RunProgramButton.Text = {'Run'; 'Program'};
            app.PbDDataButton = uibutton(app.GridLayout, 'push');
            app.PbDDataButton.ButtonPushedFcn = createCallbackFcn(app, @PbDDataButtonPushed, true);
            app.PbDDataButton.Text = {'PbD'; 'Data'};
            app.RobotArmSimulationButton = uibutton(app.GridLayout, 'push');
            app.RobotArmSimulationButton.ButtonPushedFcn = createCallbackFcn(app, @RobotArmSimulationButtonPushed, true);
            app.RobotArmSimulationButton.Text = 'Robot Arm Simulation';
            app.UpdateWaypointsButton = uibutton(app.GridLayout, 'push');
            app.UpdateWaypointsButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateWaypointsButtonPushed, true);
            app.UpdateWaypointsButton.Text = 'Update Waypoints';
            app.StopButton = uibutton(app.GridLayout, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            % Show the figure after all components are created
            app.PbDMadgwickAlgorithmSimulationUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PbD

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.PbDMadgwickAlgorithmSimulationUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PbDMadgwickAlgorithmSimulationUIFigure)
        end
    end
end