classdef waypointPublisher < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        WaypointPublisherUIFigure  matlab.ui.Figure
        Title                      matlab.ui.control.Label
        MATLABWorkspacePanel       matlab.ui.container.Panel
        SimulateButton             matlab.ui.control.Button
        UpdateWaypointsButton      matlab.ui.control.Button
        StopButton                 matlab.ui.control.Button
    end

    % Waypoint Publisher App
    % Copyright 2019 The MathWorks, Inc.
    
    properties (Access = private)           
        selectedWaypoint = 1;   % Currently selected waypoint on table
        
        robot;                  % Rigid Body Tree
        robotConfig;            % Rigid Body Tree joint configuration
        robotIK;                % Rigid Body Tree Inverse Kinematics solver
        robotIKWeights;         % Rigid Body Tree Inverse Kinematics weights
        robotIKInitGuess;       % Rigid Body Tree Inverse Kinematics initial guess
        robotEE;                % Rigid Body Tree End Effector frame
        
        waypointPub;            % Waypoint ROS publisher
        waypointTimePub;        % Waypoint time ROS publisher
    end

    methods (Access = private)
    
        function createRigidBodyTree(app)
            % Creates a rigid body tree and IK solver from the robot and end effector name fields
            evalin('base',['load ' 'gen3']);
            app.robot = evalin('base', 'gen3');
            app.robot.DataFormat = 'row';
            app.robotIK = inverseKinematics('RigidBodyTree',app.robot);
            app.robotIK.SolverParameters.MaxIterations = 10000;    % Limit the max iterations
            app.robotIKWeights = [1, 1, 1, 1, 1, 1];
            app.robotConfig = app.robot.homeConfiguration;
            app.robotIKInitGuess = app.robotConfig;
            app.robotEE = 'Gripper';    
        end
    
        function showRobot(app)
            % Displays the waypoints, and the rigid body tree IK solution a provided waypoint index "idx"
            
            % Use the home configuration if the point is invalid
            app.robotConfig = app.robot.homeConfiguration;

            % Display the rigid body tree
            show(app.robot,app.robotConfig,'Frames','off');
        end
          
        function exportVariablesToWorkspace(app)
            % Exports the waypoint and time data to the MATLAB base workspace.
            % Used when clicking the "To Workspace" and "Update Diagram" buttons
            
            fid = fopen("Try.txt", "r");
            cac = textscan(fid, '%f%f%f%f%f%f%f');
            fclose(fid);
            time = cac{1};  
            posx = cac{2} + 0.5;
            posy = cac{3};
            posz = cac{4} + 0.5;
            gyrx = cac{5} + 0.5;
            gyry = cac{6} + 1.5;
            gyrz = cac{7} + 0.5;
              
            time = rot90(time);
            pos = rot90([posz posy posx]);
            gyr = rot90([gyrz gyry gyrx]);

            assignin('base','waypointTimes',time);
            assignin('base','waypoints',pos);
            assignin('base','orientations',gyr);
          end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Create Rigid Body Tree
            createRigidBodyTree(app);
        end

        % Button pushed function: UpdateWaypointsButton
        function UpdateWaypointsButtonPushed(app, event)
            exportVariablesToWorkspace(app)
        end

        % Button pushed function: SimulateButton
        function SimulateButtonPushed(app, event)
            mdlName = 'manipTransformTrajectory';
            if ~bdIsLoaded(mdlName)
                open_system(mdlName)
            end
            set_param(mdlName,'SimulationCommand','start');
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            mdlName = 'manipTransformTrajectory';
            set_param(mdlName,'SimulationCommand','stop');    
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.WaypointPublisherUIFigure = uifigure('Visible', 'off');
            app.WaypointPublisherUIFigure.Name = 'Waypoint Publisher';
            app.Title = uilabel(app.WaypointPublisherUIFigure);
            app.Title.Text = 'Table of Waypoints';
            app.MATLABWorkspacePanel = uipanel(app.WaypointPublisherUIFigure);
            app.MATLABWorkspacePanel.Title = 'MATLAB Workspace';
            app.SimulateButton = uibutton(app.WaypointPublisherUIFigure, 'push');
            app.SimulateButton.ButtonPushedFcn = createCallbackFcn(app, @SimulateButtonPushed, true);
            app.SimulateButton.Text = 'Simulate';
            app.UpdateWaypointsButton = uibutton(app.WaypointPublisherUIFigure, 'push');
            app.UpdateWaypointsButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateWaypointsButtonPushed, true);
            app.UpdateWaypointsButton.Text = {'Update '; 'Waypoints'};
            app.StopButton = uibutton(app.WaypointPublisherUIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            % Show the figure after all components are created
            app.WaypointPublisherUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = waypointPublisher

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.WaypointPublisherUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.WaypointPublisherUIFigure)
        end
    end
end